classdef tabbedPDF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        NewODFMenu                     matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        NumberofDirectionsDropDownLabel  matlab.ui.control.Label
        Main_DirectionsDropdown        matlab.ui.control.DropDown
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        SaveandCalculateODFButton      matlab.ui.control.Button
        CrystalSymmetryDropDownLabel   matlab.ui.control.Label
        CrystalSymmetryDropDown        matlab.ui.control.DropDown
        TutorialOnButton               matlab.ui.control.Button
        D_1Tab                         matlab.ui.container.Tab
        PoleLabel                      matlab.ui.control.Label
        D1_DirectionEditField          matlab.ui.control.EditField
        D1_LoadImageButton             matlab.ui.control.Button
        D1_RunCentering                matlab.ui.control.Button
        D1_RunTransform                matlab.ui.control.Button
        D1_NewLayerButton              matlab.ui.control.Button
        SelectLayerDropDownLabel       matlab.ui.control.Label
        D1_SelectLayerDropDown         matlab.ui.control.DropDown
        LayerContourLevelEditFieldLabel  matlab.ui.control.Label
        D1_LayerContourLevelEditField  matlab.ui.control.NumericEditField
        D1_ActiveLayerLabel            matlab.ui.control.Label
        D1_ActiveLayerLabelUpdateable  matlab.ui.control.Label
        D1_UpdateLayerButton           matlab.ui.control.Button
        D1_AddPolygonButton            matlab.ui.control.Button
        D_2Tab                         matlab.ui.container.Tab
        D_3Tab                         matlab.ui.container.Tab
        D_4Tab                         matlab.ui.container.Tab
    end

    
    properties (Access = public)
        im = cell(4); % holds loaded image for each tab
        ls = cell(4);
        ax % need an axis handle to make life easy
        bc % boundary circle
        dots % hold the dot handles so I don't have to
        tutorial % helps to have one, right?
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tutorial = PFDTutorial;
        end

        % Button pushed function: D1_LoadImageButton
        function D1_LoadImageButtonPushed(app, event)
            OnLoadImageButtonPressed(app,1)
        end

        % Button pushed function: D1_RunCentering
        function D1_RunCenteringButtonPushed(app, event)
            OnRunCenteringButtonPressed(app,1)
        end

        % Button pushed function: D1_RunTransform
        function D1_RunTransformButtonPushed(app, event)
            OnRunTransformButtonPressed(app,1)
        end

        % Button pushed function: D1_NewLayerButton
        function D1_NewLayerButtonPushed(app, event)
            OnNewLayerButtonPressed(app, 1)
        end

        % Value changed function: D1_SelectLayerDropDown
        function D1_SelectLayerDropDownValueChanged(app, event)
            value = app.D1_SelectLayerDropDown.Value;
            app.D1_LayerContourLevelEditField.Value = app.ls{1}.Stack{str2num(value)}.contourLevel;
            app.D1_ActiveLayerLabelUpdateable.Text = value;
        end

        % Button pushed function: D1_UpdateLayerButton
        function D1_UpdateLayerButtonPushed(app, event)
            cl = app.D1_LayerContourLevelEditField.Value;
            la = app.D1_SelectLayerDropDown.Value;
        
            app.ls{1}.Stack{str2num(la)}.contourLevel = cl;
        end

        % Button pushed function: TutorialOnButton
        function TutorialOnButtonPushed(app, event)
            app.tutorial.ToggleTutorial();
            value = app.tutorial.QueryTutorial();
            if value == true
                app.TutorialOnButton.Text = 'Tutorial: On';
            else
                app.TutorialOnButton.Text = 'Tutorial: Off';
            end
        end

        % Button pushed function: D1_AddPolygonButton
        function D1_AddPolygonButtonPushed(app, event)
            OnAddPolygonButtonPressed(app, 1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.NewODFMenu = uimenu(app.Menu);
            app.NewODFMenu.Text = 'New ODF';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.NumberofDirectionsDropDownLabel = uilabel(app.MainTab);
            app.NumberofDirectionsDropDownLabel.Text = 'Number of Directions';
            app.Main_DirectionsDropdown = uidropdown(app.MainTab);
            app.Main_DirectionsDropdown.Items = {'1', '2', '3', '4'};
            app.Main_DirectionsDropdown.Value = '1';
            app.NameEditFieldLabel = uilabel(app.MainTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.MainTab, 'text');
            app.SaveandCalculateODFButton = uibutton(app.MainTab, 'push');
            app.SaveandCalculateODFButton.Text = 'Save and Calculate ODF';
            app.CrystalSymmetryDropDownLabel = uilabel(app.MainTab);
            app.CrystalSymmetryDropDownLabel.Text = 'Crystal Symmetry';
            app.CrystalSymmetryDropDown = uidropdown(app.MainTab);
            app.CrystalSymmetryDropDown.Items = {'BCC', 'FCC', 'HCP'};
            app.CrystalSymmetryDropDown.Value = 'BCC';
            app.TutorialOnButton = uibutton(app.MainTab, 'push');
            app.TutorialOnButton.ButtonPushedFcn = createCallbackFcn(app, @TutorialOnButtonPushed, true);
            app.TutorialOnButton.Text = 'Tutorial: On';
            app.D_1Tab = uitab(app.TabGroup);
            app.D_1Tab.Title = 'D_1';
            app.PoleLabel = uilabel(app.D_1Tab);
            app.PoleLabel.Text = 'Pole';
            app.D1_DirectionEditField = uieditfield(app.D_1Tab, 'text');
            app.D1_DirectionEditField.Value = '1 1 0';
            app.D1_LoadImageButton = uibutton(app.D_1Tab, 'push');
            app.D1_LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @D1_LoadImageButtonPushed, true);
            app.D1_LoadImageButton.Text = 'Load Image';
            app.D1_RunCentering = uibutton(app.D_1Tab, 'push');
            app.D1_RunCentering.ButtonPushedFcn = createCallbackFcn(app, @D1_RunCenteringButtonPushed, true);
            app.D1_RunCentering.Text = 'Run Centering';
            app.D1_RunTransform = uibutton(app.D_1Tab, 'push');
            app.D1_RunTransform.ButtonPushedFcn = createCallbackFcn(app, @D1_RunTransformButtonPushed, true);
            app.D1_RunTransform.Text = 'Run Transform';
            app.D1_NewLayerButton = uibutton(app.D_1Tab, 'push');
            app.D1_NewLayerButton.ButtonPushedFcn = createCallbackFcn(app, @D1_NewLayerButtonPushed, true);
            app.D1_NewLayerButton.Text = 'New Layer';
            app.SelectLayerDropDownLabel = uilabel(app.D_1Tab);
            app.SelectLayerDropDownLabel.Text = 'Select Layer';
            app.D1_SelectLayerDropDown = uidropdown(app.D_1Tab);
            app.D1_SelectLayerDropDown.Items = {};
            app.D1_SelectLayerDropDown.ValueChangedFcn = createCallbackFcn(app, @D1_SelectLayerDropDownValueChanged, true);
            app.D1_SelectLayerDropDown.Value = {};
            app.LayerContourLevelEditFieldLabel = uilabel(app.D_1Tab);
            app.LayerContourLevelEditFieldLabel.Text = 'Layer Contour Level';
            app.D1_LayerContourLevelEditField = uieditfield(app.D_1Tab, 'numeric');
            app.D1_ActiveLayerLabel = uilabel(app.D_1Tab);
            app.D1_ActiveLayerLabel.Text = 'Level';
            app.D1_ActiveLayerLabelUpdateable = uilabel(app.D_1Tab);
            app.D1_ActiveLayerLabelUpdateable.Text = 'None';
            app.D1_UpdateLayerButton = uibutton(app.D_1Tab, 'push');
            app.D1_UpdateLayerButton.ButtonPushedFcn = createCallbackFcn(app, @D1_UpdateLayerButtonPushed, true);
            app.D1_UpdateLayerButton.Text = 'Update Layer';
            app.D1_AddPolygonButton = uibutton(app.D_1Tab, 'push');
            app.D1_AddPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @D1_AddPolygonButtonPushed, true);
            app.D1_AddPolygonButton.Text = 'Add Polygon';
            app.D_2Tab = uitab(app.TabGroup);
            app.D_2Tab.Title = 'D_2';
            app.D_3Tab = uitab(app.TabGroup);
            app.D_3Tab.Title = 'D_3';
            app.D_4Tab = uitab(app.TabGroup);
            app.D_4Tab.Title = 'D_4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tabbedPDF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end